classdef LoadDataDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadDataUIFigure              matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LoadDCbackgroundlevelsButton  matlab.ui.control.Button
        ErrorLabel                    matlab.ui.control.Label
        LoadABFinalButton             matlab.ui.control.Button
        Header                        matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        StepSizeEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel        matlab.ui.control.Label
        UnitDropDown                  matlab.ui.control.DropDown
    end


    properties (Access = private)
        CallingApp % Main app that called it
        preDataTable = table(); % Temporary data table while loading data
        preDataTime % Temporary property to hold the data time while loading
        pixelCoordinates = [] % array to hold all the pixel coordinates
        fullName
        DClevelTable % Table to hold DC levels
    end

    methods (Access = private)

        % This function loads the data from the user selected folder
        function readData(app)
            % Get user folder
            FolderPath = uigetdir;

            % Check if user selected folder
            if FolderPath == 0
                % Warn user if they cancel folder selection
                app.ErrorLabel.Text = "Select working folder to continue...";
                app.ErrorLabel.Visible = "on";
                app.LoadABFinalButton.Enable = "on";

                % Open folder and read files
            else
                % Check if data was already loaded
                % Data might be loaded, but wrong step size
                % Instead of reloading data
                if isempty(app.preDataTable)
                    % Get the content of the folder
                    FolderContent = dir(FolderPath);

                    % Get only relevant files "*Y.tdms"
                    acceptedFilesIndex = endsWith({FolderContent.name}, 'Y.tdms', "IgnoreCase", true);
                    acceptedFiles = string({FolderContent(acceptedFilesIndex).name});

                    % Get last two directories to direct users to folder
                    temp = split(FolderPath, '\');
                    friendlyName = join([temp(end-1), temp(end)], '\');
                    app.fullName = FolderPath;

                    % Ensure the files do exist
                    if isempty(acceptedFiles)
                        app.ErrorLabel.Text = sprintf("%s\ndoes not contain '*Y.tdms' files.\n" + ...
                            "Please try different folder", string(friendlyName));
                        app.ErrorLabel.Visible = "on";

                    else
                        % Load all *Y.tdms files
                        for fileIndex = 1:length(acceptedFiles)
                            file = char(acceptedFiles(fileIndex));

                            % Get full filepath
                            filePath = [FolderPath,'\',file];

                            % Extract X and Y co-ordinates
                            fileCoordinate = extractBetween(file, "nm", ".tdms");
                            % Add pixels to pixelCoordinates
                            app.pixelCoordinates = [app.pixelCoordinates; string(fileCoordinate)];

                            % Open and extract TDMS data
                            TDMScontent = TDMS_readTDMSFile(filePath);

                            % Extract specific indices
                            CH0GroupIndex = strcmp(TDMScontent.groupNames, "CH0");
                            AbsChanIndex = strcmp(string(TDMScontent.chanNames{CH0GroupIndex}), 'final A-B');
                            AbsDataIndex = TDMScontent.chanIndices{1,CH0GroupIndex}(AbsChanIndex);

                            % Extract time indices
                            TimeGroupIndex = strcmp(string(TDMScontent.groupNames),'Time');
                            TimeDataIndex = TDMScontent.chanIndices{TimeGroupIndex};

                            % Extract the Time data
                            Time = TDMScontent.data{1,TimeDataIndex}';

                            % Extract specific data, and convert to column
                            Abs = TDMScontent.data{1,AbsDataIndex}';

                            % Get log data, as it is the data we need
                            [LogTimeArray, LogAbsArray] = lin2log_TAS(Time, Abs);

                            % Add data time to table if table is empty
                            % We only need to get time once, as the data
                            % time is the same
                            if isempty(app.preDataTime)
                                app.preDataTime = LogTimeArray;
                            end

                            % Add the logData to table
                            app.preDataTable = addvars(app.preDataTable, LogAbsArray, 'NewVariableNames', string(fileCoordinate));
                        end % end for
                    end
                end
            end
        end

        function makeImageData(app)
            % Check if valid step size
            if app.StepSizeEditField.Value == 0
                throw(MException("ImageData:InvalidStepSize", "The stepsize chosen is invalid"));
            else
                % get the numeric values of the co-ordinates
                coordinates_2d = str2double([ extractBefore(app.pixelCoordinates(:), 'X'), extractBetween(app.pixelCoordinates(:), 'X', 'Y')]);

                % Confirm dataset is a square
                if mod(numel(factor(length(coordinates_2d))),2) ~= 0
                    throw(MException("ImageData:SizeError", "Can only create square image"))
                end
                % Converter struct, change this if more stepsizes are available
                conversions = struct("nm", 1, "um", 1e-3, "mm", 1e-6);
                % Extract μ as it is not standard
                key = app.UnitDropDown.Value;
                if strcmp(key, "μm")
                    key = "um";
                end
                % Obtain stepsize
                stepSize = conversions.(key)/app.StepSizeEditField.Value;
                % Obtain new co-ordinates that have been adjusted
                coordinates_2d = round(coordinates_2d * stepSize);
                % Confirm stepSize properly seperates data
                if length(unique(coordinates_2d(:,1))) ~= sqrt(length(coordinates_2d))
                    throw(MException("ImageData:StepSizeError", "StepSize did not create square image"))
                end
                % Sort data by X then Y and obtain sort key
                [~, sortIndex] = sortrows(coordinates_2d, [1,2]);
                app.pixelCoordinates = app.pixelCoordinates(sortIndex);
                % Sort table as well
                % It is sorted to maintain consitency across variables
                app.preDataTable = app.preDataTable(:, sortIndex(:,:));
                % Reshape data into square for future use
                app.pixelCoordinates = reshape(app.pixelCoordinates, [sqrt(length(app.pixelCoordinates)), sqrt(length(app.pixelCoordinates))])';
            end
        end


        function readDClevel(app)
            % Get user folder
            FolderPath = uigetdir;

            % Check if user selected folder
            if FolderPath == 0
                % Warn user if they cancel folder selection
                app.ErrorLabel.Text = "Select working folder to continue...";
                app.ErrorLabel.Visible = "on";
                app.LoadABFinalButton.Enable = "on";

                % Open folder and read files
            else
                %Clear previous data
                app.DClevelTable = table();

                % Get the content of the folder
                FolderContent = dir(FolderPath);

                % Get only relevant files "*Y.tdms"
                acceptedFilesIndex = endsWith({FolderContent.name}, 'Y.tdms', "IgnoreCase", true);
                acceptedFiles = string({FolderContent(acceptedFilesIndex).name});

                % Get last two directories to direct users to folder
                temp = split(FolderPath, '\');
                friendlyName = join([temp(end-1), temp(end)], '\');
                app.fullName = FolderPath;

                % Ensure the files do exist
                if isempty(acceptedFiles)
                    app.ErrorLabel.Text = sprintf("%s\ndoes not contain '*Y.tdms' files.\n" + ...
                        "Please try different folder", string(friendlyName));
                    app.ErrorLabel.Visible = "on";

                else
                    % Load all *Y.tdms files
                    for fileIndex = 1:length(acceptedFiles)
                        file = char(acceptedFiles(fileIndex));

                        % Get full filepath
                        filePath = [FolderPath,'\',file];

                        % Extract X and Y co-ordinates
                        fileCoordinate = extractBetween(file, "nm", ".tdms");
                        % Add pixels to pixelCoordinates
                        app.pixelCoordinates = [app.pixelCoordinates; string(fileCoordinate)];

                        % Open and extract TDMS data
                        TDMScontent = TDMS_readTDMSFile(filePath);

                        % Extract specific indices for the background
                        % levels

                        BkgGroupIndex = strcmp(TDMScontent.groupNames,'Background level');
                        AvgBkgChanIndex = strcmp(TDMScontent.chanNames{BkgGroupIndex},'Avg bkg level (V)');
                        AvgBkgDataIndex = TDMScontent.chanIndices{1,BkgGroupIndex}(AvgBkgChanIndex);

                        % Extract DC background level data
                        DClevel = TDMScontent.data{1,AvgBkgDataIndex};

                        % Add DC level data to table
                        app.DClevelTable = addvars(app.DClevelTable, DClevel, 'NewVariableNames', string(fileCoordinate));
                    end %
                end

            end
        end

        function makeImageDataDClevel(app)
            % Check if valid step size
            if app.StepSizeEditField.Value == 0
                throw(MException("ImageData:InvalidStepSize", "The stepsize chosen is invalid"));
            else
                % get the numeric values of the co-ordinates
                coordinates_2d = str2double([ extractBefore(app.pixelCoordinates(:), 'X'), extractBetween(app.pixelCoordinates(:), 'X', 'Y')]);

                % Confirm dataset is a square
                if mod(numel(factor(length(coordinates_2d))),2) ~= 0
                    throw(MException("ImageData:SizeError", "Can only create square image"))
                end
                % Converter struct, change this if more stepsizes are available
                conversions = struct("nm", 1, "um", 1e-3, "mm", 1e-6);
                % Extract μ as it is not standard
                key = app.UnitDropDown.Value;
                if strcmp(key, "μm")
                    key = "um";
                end
                % Obtain stepsize
                stepSize = conversions.(key)/app.StepSizeEditField.Value;
                % Obtain new co-ordinates that have been adjusted
                coordinates_2d = round(coordinates_2d * stepSize);
                % Confirm stepSize properly seperates data
                if length(unique(coordinates_2d(:,1))) ~= sqrt(length(coordinates_2d))
                    throw(MException("ImageData:StepSizeError", "StepSize did not create square image"))
                end
                % Sort data by X then Y and obtain sort key
                [~, sortIndex] = sortrows(coordinates_2d, [1,2]);
                app.pixelCoordinates = app.pixelCoordinates(sortIndex);
                % Sort table as well
                % It is sorted to maintain consitency across variables
                app.DClevelTable = app.DClevelTable(:, sortIndex(:,:));
                % Reshape data into square for future use
                app.pixelCoordinates = reshape(app.pixelCoordinates, [sqrt(length(app.pixelCoordinates)), sqrt(length(app.pixelCoordinates))])';
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStart(app, mainApp)
            % Reference to the main application
            app.CallingApp = mainApp;
        end

        % Key press function: LoadDataUIFigure
        function onKeypress(app, event)
            key = event.Key;
            if strcmp(key, "return")
                onSubmitPushed(app);
            end
        end

        % Button pushed function: LoadABFinalButton
        function onSubmitPushed(app, event)
            app.LoadABFinalButton.Enable = "off";
            % Check if data already exists
            % Is called once when button is clicked
            if isempty(app.preDataTable)
                % Read the data from folder
                readData(app);
            end

            % If button is clicked twice
            if ~isempty(app.preDataTable)
                try
                    % Create image data
                    makeImageData(app);
                    % Pass data back to main App
                    passDataToMain(app.CallingApp, app.preDataTable, app.preDataTime, app.pixelCoordinates, app.fullName);
                    % Close the dialog
                    delete(app);

                    % Error handlers
                catch ME
                    switch ME.identifier
                        case "ImageData:InvalidStepSize"
                            disp("Invalid Step Size");
                            app.ErrorLabel.Text = "Invalid Step Size";
                        case "ImageData:SizeError"
                            disp("Dataset is not square")
                            app.ErrorLabel.Text = "Dataset is not square";
                        case "ImageData:StepSizeError"
                            disp("Stepsize could not create square image")
                            app.ErrorLabel.Text = "Stepsize could not create square image";
                        otherwise
                            disp(ME.message)
                    end
                    app.ErrorLabel.Visible = "on";
                    app.LoadABFinalButton.Enable = "on";
                end
            else
                app.CallingApp.InfoLabel.Text = "Could not load data";
                app.CallingApp.InfoLabel.Visible = "on";
                app.CallingApp.LoadDataButton.Enable = "on";
                delete(app);
            end


        end

        % Close request function: LoadDataUIFigure
        function onClose(app, event)
            if isempty(app.preDataTable)
                app.CallingApp.InfoLabel.Text = "Did not load data";
                app.CallingApp.InfoLabel.Visible = "on";
                app.CallingApp.LoadDataButton.Enable = "on";
            end
            delete(app)

        end

        % Button pushed function: LoadDCbackgroundlevelsButton
        function LoadDCbackgroundlevelsButtonPushed(app, event)
            readDClevel(app)

            try
                % Create image DC level data
                makeImageDataDClevel(app);

                % Error handlers
            catch ME
                switch ME.identifier
                    case "ImageData:InvalidStepSize"
                        disp("Invalid Step Size");
                        app.ErrorLabel.Text = "Invalid Step Size";
                    case "ImageData:SizeError"
                        disp("Dataset is not square")
                        app.ErrorLabel.Text = "Dataset is not square";
                    case "ImageData:StepSizeError"
                        disp("Stepsize could not create square image")
                        app.ErrorLabel.Text = "Stepsize could not create square image";
                    otherwise
                        disp(ME.message)
                end
                app.ErrorLabel.Visible = "on";
                app.LoadABFinalButton.Enable = "on";
            end


            % Export data to base workspace
            assignin("base","DClevels", app.DClevelTable);

            DClevelMatrix = table2array(app.DClevelTable);
            DClevelMatrix = reshape(DClevelMatrix, [sqrt(length(DClevelMatrix)) sqrt(length(DClevelMatrix))]);
            assignin("base","DClevelsMatrix", DClevelMatrix);

            assignin("base","PixelCoordinates", app.pixelCoordinates);

            % Close the dialog
            delete(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadDataUIFigure = uifigure('Visible', 'off');
            app.LoadDataUIFigure.Name = 'Load Data';
            app.LoadDataUIFigure.Icon = 'Logo.png';
            app.LoadDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @onClose, true);
            app.LoadDataUIFigure.KeyPressFcn = createCallbackFcn(app, @onKeypress, true);
            app.GridLayout = uigridlayout(app.LoadDataUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UnitDropDown = uidropdown(app.GridLayout2);
            app.UnitDropDown.Items = {'nm', 'μm', 'mm'};
            app.UnitDropDown.Value = 'mm';
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout2);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.StepSizeEditField.Limits = [0 Inf];
            app.Header = uilabel(app.GridLayout);
            app.Header.Text = {'Enter the step size of dataset'; ''};
            app.LoadABFinalButton = uibutton(app.GridLayout, 'push');
            app.LoadABFinalButton.ButtonPushedFcn = createCallbackFcn(app, @onSubmitPushed, true);
            app.LoadABFinalButton.Text = {'Load A-B Final'; ''};
            app.ErrorLabel = uilabel(app.GridLayout);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Tooltip = {''};
            app.ErrorLabel.Text = '';
            app.LoadDCbackgroundlevelsButton = uibutton(app.GridLayout, 'push');
            app.LoadDCbackgroundlevelsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDCbackgroundlevelsButtonPushed, true);
            app.LoadDCbackgroundlevelsButton.Text = 'Load DC background levels';
            % Show the figure after all components are created
            app.LoadDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadDataDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)onStart(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadDataUIFigure)
        end
    end
end