classdef TAM_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        ExportVariablesButton   matlab.ui.control.Button
        GridLayout8             matlab.ui.container.GridLayout
        ColorScaleTimesLabel    matlab.ui.control.Label
        GridLayout10            matlab.ui.container.GridLayout
        MaxColorTime            matlab.ui.control.NumericEditField
        MinColorTime            matlab.ui.control.NumericEditField
        GridLayout9             matlab.ui.container.GridLayout
        MaxLabel                matlab.ui.control.Label
        MinLabel                matlab.ui.control.Label
        ExportDataButton        matlab.ui.control.Button
        GridLayout7             matlab.ui.container.GridLayout
        T0TimeEditField         matlab.ui.control.NumericEditField
        T0TimeEditFieldLabel    matlab.ui.control.Label
        RectDrawInfo            matlab.ui.control.Label
        SelectRegionButton      matlab.ui.control.Button
        GridLayout6             matlab.ui.container.GridLayout
        RemoveButton            matlab.ui.control.Button
        AddRegionButton         matlab.ui.control.Button
        GridLayout5             matlab.ui.container.GridLayout
        ColorSchemeLabel        matlab.ui.control.Label
        ColorSchemeButtonGroup  matlab.ui.container.ButtonGroup
        GrayscaleButton         matlab.ui.control.RadioButton
        RGBButton               matlab.ui.control.RadioButton
        IntensityDerivedButton  matlab.ui.control.RadioButton
        InfoLabel               matlab.ui.control.Label
        LoadDataButton          matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        RightMainGrid           matlab.ui.container.GridLayout
        GridLayout14            matlab.ui.container.GridLayout
        AvgPlot                 matlab.ui.control.UIAxes
        PixelPlot               matlab.ui.control.UIAxes
        TabGroup                matlab.ui.container.TabGroup
        KinPlotTab              matlab.ui.container.Tab
        GridLayout12            matlab.ui.container.GridLayout
        KinPlot                 matlab.ui.control.UIAxes
        HistPlotTab             matlab.ui.container.Tab
        GridLayout13            matlab.ui.container.GridLayout
        HistPlot                matlab.ui.control.UIAxes
        BkgrdPlotTab            matlab.ui.container.Tab
        GridLayout15            matlab.ui.container.GridLayout
        Panel                   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Accesible by app only
    properties (Access = private)
        % CONSTANT VARIABLES. SHOULD NOT BE CHANGED ONCE SET
        DataTable % complete loaded dataset for time > 0
        DataTime % complete time data for all time > 0
        DataDC % complete DC background data
        MaxDataTime % Keeps track of maximum value in DataTime variable
        PixelCoordinates % n x n cell of all the pixels in the image
        FullName % full path of the loaction of dataset
        DataLoaderDialogApp % Data loading dialog

        % Should only be changed on specific cases
        CurrentKineticData = []

        % Dynamic Variables
        T0_Time % The current t0 time
        TimeAbove0 % Filtered time above t0 time
        DataAbove0 % Filtered current kinetic data where time > t0
        MinColorIndex % Variable to keep track of the turbo scale indexes
        MaxColorIndex % Variable to keep track of the turbo scale indexes
        T0_line % ROI line with a value of T0_Time (t0 line for avgplot)
        T0_line_kinPlot % t0 Line for the kinplot
        T50_Line % t50% line that will be updated for every data set
        ActivePixel = -1 % Tracks currently selected pixel


        % Fit function variables, change whenever generateFITFCNS is called
        FitX % Holds time data to fit
        FitY % Holds dataset to fit
        FitFunctions % Hold all the fit functions for every pixel
        FittedPlot % Current pixel fitted data plot


        % Export to folder data
        ImageSaveData % Variable to hold the imagedata for saving
        ImagePlot % keep track of the image plot


        % Selected region variables
        CurrentSelectedAreaData = [] % Array to hold current selected Data
        DrawnRectangles = [] % hold the Rectangles currently drawn
        SelectedRegions = [] % hold unique rectangle areas i.e. coordinates covered by DrawnRectangles

        % Variables from old app
        preDataTable = table(); % Temporary data table while loading data
        preDataTime % Temporary property to hold the data time while loading
        pixelCoordinates = [] % array to hold all the pixel coordinates
        fullName
        DClevelTable % Table to hold DC levels


        %% For data with listeners that change, to avoid memory overhead
        CurrentPlot % Keep track of current kinetic Plot
        SelectedRegionPlot % Keep track of selected region
        rectangleID = 1 % Metadata for tracking active and deleted rectangles


        % Workspace variables
        ExportT50Times  % Keeps track of the current t50 times if needed for extra analysis
        ExportT0Int     % Matrix of the intensities at t0 if needed for extra analysis
    end

    properties (Access = public)
        Channels % Holds the chosen channel names of all .tdms files
        RedGreen % Holds boolean values to determine colour of Channels text
        finalX % Property to hold XTicks
        finalY % Property to hold YTicks
    end

    % Accesible by app only
    methods (Access = private)

        % This function loads the data from the user selected folder
        function readData(app)
            % Get user folder
            FolderPath = uigetdir;

            % Heirarchy of wanted channels
            count = 0;
            chanFinal = ["final A-B","A-Test  dOD"]; %What about A-Test processed??
            chanRaw = ["B-Test  raw", "A-Test  raw"];

            mix = [chanRaw,chanFinal];

            % Check if user selected folder
            if FolderPath == 0
                % Warn user if they cancel folder selection
                app.InfoLabel.Text = "Select working folder to continue...";
                app.InfoLabel.Visible = "on";
                app.LoadDataButton.Enable = "on";

                % Open folder and read files
            else
                % Clear/reset previous data
                cla(app.AvgPlot);
                cla(app.KinPlot);
                cla(app.PixelPlot);

                app.Channels = strings(0);
                app.RedGreen = [];
                app.preDataTable = table();
                app.preDataTime = table();
                app.DClevelTable = table();
                app.pixelCoordinates = strings(0);
                app.KinPlotTab.Title = "KinPlot";
                app.KinPlotTab.ForegroundColor = [0.00,0.00,0.00];

                % Get the content of the folder
                FolderContent = dir(FolderPath);

                % Get only relevant files "*Y.tdms"
                acceptedFilesIndex = endsWith({FolderContent.name}, 'Y.tdms', "IgnoreCase", true);
                acceptedFiles = string({FolderContent(acceptedFilesIndex).name});

                % Get last two directories to direct users to folder
                temp = split(FolderPath, '\');
                friendlyName = join([temp(end-1), temp(end)], '\');
                app.fullName = FolderPath;

                % Ensure the files do exist
                if isempty(acceptedFiles)
                    app.InfoLabel.Text = sprintf("%s\ndoes not contain '*Y.tdms' files.\n" + ...
                        "Please try different folder", string(friendlyName));
                    app.InfoLabel.Visible = "on";

                else
                    % Load all *Y.tdms files
                    for fileIndex = 1:length(acceptedFiles)
                        count = count + 1;
                        file = char(acceptedFiles(fileIndex));

                        % Get full filepath
                        filePath = [FolderPath,'\',file];

                        % Extract X and Y co-ordinates
                        fileCoordinate = extractBetween(file, "nm", ".tdms");

                        % Add pixels to pixelCoordinates
                        app.pixelCoordinates = [app.pixelCoordinates; string(fileCoordinate)];

                        % Open and extract TDMS data
                        TDMScontent = TDMS_readTDMSFile(filePath);

                        % Extract specific indices
                        CH0GroupIndex = strcmp(TDMScontent.groupNames, "CH0");
                        ChannelIndices = TDMScontent.chanNames{1,CH0GroupIndex};

                        if count == 1
                            logical = contains(ChannelIndices,mix);
                            options = ChannelIndices(logical);

                            opts = options;
                            % opts{1,end+1} = 'Cancel';

                            name = uiconfirm(app.UIFigure,"Please choose a data column: ","AbsChanIndex","Options",opts);
                        end

                        if contains(name,chanFinal)
                            selectedChans = chanFinal;
                            app.AvgPlot.YLabel.String = "ΔOD or %Abs";
                            app.KinPlot.YLabel.String = "ΔOD or %Abs";
                        else
                            selectedChans = chanRaw;
                            app.AvgPlot.YLabel.String = "Voltage (V)";
                            app.KinPlot.YLabel.String = "Voltage (V)";
                        end

                        % Continue extracting indices
                        AbsChanIndex = strcmp(string(ChannelIndices), name);

                        if ~any(AbsChanIndex)
                            given = contains(ChannelIndices,selectedChans);
                            NewOpts = ChannelIndices(given);

                            AbsChanIndex = strcmp(ChannelIndices,NewOpts{1,1});
                            app.Channels = [app.Channels;string(NewOpts{1,1})];
                            app.RedGreen = [app.RedGreen;0];
                        else
                            app.Channels = [app.Channels;string(name)];
                            app.RedGreen = [app.RedGreen;1];
                        end

                        AbsDataIndex = TDMScontent.chanIndices{1,CH0GroupIndex}(AbsChanIndex);

                        if ~any(TDMScontent.data{1,AbsDataIndex}(1:5)) == 1 && ~any(TDMScontent.data{1,AbsDataIndex}(end-4:end)) == 1
                            app.Channels(end) = [];
                            app.RedGreen(end) = [];
                            continue % DO NOT ADD IF COLUMN IS FULL OF ZEROS
                        end

                        BkgGroupIndex = strcmp(TDMScontent.groupNames,'Background level');
                        AvgBkgChanIndex = strcmp(TDMScontent.chanNames{BkgGroupIndex},'Avg bkg level (V)');
                        AvgBkgDataIndex = TDMScontent.chanIndices{1,BkgGroupIndex}(AvgBkgChanIndex);

                        % Extract DC background level data
                        DClevel = TDMScontent.data{1,AvgBkgDataIndex};

                        % Extract time indices
                        TimeGroupIndex = strcmp(string(TDMScontent.groupNames),'Time');
                        TimeDataIndex = TDMScontent.chanIndices{TimeGroupIndex};

                        % Extract the Time data
                        Time = TDMScontent.data{1,TimeDataIndex}';

                        % Extract specific data, and convert to column
                        Abs = TDMScontent.data{1,AbsDataIndex}';

                        % Get log data, as it is the data we need
                        [LogTimeArray, LogAbsArray] = lin2log_TAS(Time, Abs);

                        % Add data time to table if table is empty
                        % We only need to get time once, as the data
                        % time is the same
                        if isempty(app.preDataTime)
                            app.preDataTime = LogTimeArray;
                        end

                        % Add the logData to table
                        app.preDataTable = addvars(app.preDataTable, LogAbsArray, 'NewVariableNames', string(fileCoordinate));

                        % Add DC level data to table
                        app.DClevelTable = addvars(app.DClevelTable, DClevel, 'NewVariableNames', string(fileCoordinate));

                    end
                end
            end
        end

        function makeImageData(app)
            % Get the numeric values of the co-ordinates
            coordinates_2d = str2double([ extractBefore(app.pixelCoordinates(:), 'X'), extractBetween(app.pixelCoordinates(:), 'X', 'Y')]);
            len = length(coordinates_2d);

            % Confirm dataset is a square
            if mod(sqrt(len),1) ~=0
                throw(MException("ImageData:SizeError", "Can only create square image"))
            end

            % Sort data by X then Y generally
            [sortedCoord, ~] = sortrows(coordinates_2d, [1,2]);
            n = sqrt(length(app.pixelCoordinates));

            % Reshape into square
            sortedCoordX = reshape(sortedCoord(:,1), [n,n]);
            sortedCoordY = reshape(sortedCoord(:,2), [n,n]);

            % Resort since y values are treated secondary
            resortedY = sort(sortedCoordY,1);

            % Calculate differences and take mean to find step
            diffX = diff(sortedCoordX,1,2);
            diffY = diff(resortedY,1,1);

            avgX = mean(mean(diffX));
            avgY = mean(mean(diffY));

            step = mean([avgX avgY])*1e-3;

            stepSize = round(step,1); % Rounded value in Microns

            % Start from lowest coordinate values and add stepsize value
            app.finalX = sortedCoordX(1);
            app.finalY = resortedY(1);

            for i = 2:n
                app.finalX = [app.finalX;app.finalX(i-1)+stepSize*1e3];
                app.finalY = [app.finalY;app.finalY(i-1)+stepSize*1e3];
            end

            % Turn coordinates into microns
            app.finalX = app.finalX * 1e-3;
            app.finalY = app.finalY * 1e-3;

            % Multiply by all coordinates by same precision value (stepSize)
            coordinates_2d = round(coordinates_2d/stepSize);
            precision = round(coordinates_2d * stepSize * 1e-3);

            % Sort data by X then Y and obtain sort key
            % THIS MIGHT BREAK DOWN IN FUTURE FILE. MIGHT HAVE TO
            % INVESTIGATE
            [~, sortIndex] = sortrows(precision,[1,2]);

            app.pixelCoordinates = app.pixelCoordinates(sortIndex);

            % Reshape data into square for future use
            disp("Sorted files in square format: ")
            app.pixelCoordinates = reshape(app.pixelCoordinates, [n,n])';

            % Command window output to reference with heatmap
            SortedFiles = app.pixelCoordinates';
            SortedFiles = flip(SortedFiles);
            disp(SortedFiles);

            % Sort tables as well
            % It is sorted to maintain consitency across variables
            app.preDataTable = app.preDataTable(:, sortIndex(:,:));
            app.DClevelTable = app.DClevelTable(:, sortIndex(:,:));
        end

        % Function to plot the overall average data
        function plotAverage(app)
            % Obtain average data
            average_data = mean(app.DataTable{:,:}, 2);

            % Plot data
            line(app.AvgPlot, app.DataTime, average_data);
            app.AvgPlot.Visible = "on";
            app.KinPlot.Visible = "on";

            % Obtain initial t0%
            % Time at which we have highest intensity
            initial_t0 = app.DataTime(average_data == max(average_data));

            % Set T0_Time to the initial to
            app.T0_Time = initial_t0;
            % Set initial begining index to T0 time
            app.MinColorIndex = app.T0_Time;
            app.MinColorTime.Value = app.MinColorIndex;

            %Set initial ending color index
            app.MaxColorIndex = app.MaxDataTime;
            app.MaxColorTime.Value = app.MaxColorIndex;
            % Display value of T0 time
            app.T0TimeEditField.Value = app.T0_Time;

            % Draw the t_0 lines on both plots
            app.T0_line = images.roi.Line(app.AvgPlot, "Position",[initial_t0 -1; initial_t0 1] ,"Deletable",false,"InteractionsAllowed","translate");
            app.T0_line_kinPlot = xline(app.KinPlot, app.T0_Time, "LineWidth",1, "DisplayName","");

            % Add listeners to t0_line
            addlistener(app.T0_line, "MovingROI", @(src, evt) handleLineEvent(app, src, evt, app.T0_line_kinPlot));
            % draw translatable line
            addlistener(app.T0_line, "ROIMoved", @(src, evt) handleLineEvent(app, src, evt));

            % Draw a y=0 line
            yline(app.AvgPlot, 0);
        end

        % Function to handle ROI Line events
        function handleLineEvent(app,src,evt,t0_line_kinPlot)
            % Obtain event name
            eventName = evt.EventName;

            switch(eventName)
                % Line is moving
                case{'MovingROI'}
                    % Set X boundaries
                    if src.Position(1) > app.MaxDataTime
                        % Line cannot go above plot
                        src.Position = [app.MaxDataTime -1; app.MaxDataTime 1];
                    elseif src.Position(1) < min(app.DataTime)
                        % Line cannot go below plot
                        src.Position = [min(app.DataTime) -1; min(app.DataTime) 1];
                    end
                    % Move line in KinPlot to value of ROI line
                    t0_line_kinPlot.Value = src.Position(1);

                    % New value of T_0 is the closest value of where the
                    % line is
                    [~, t0_index] = min(abs(app.DataTime - src.Position(1)));
                    app.T0_Time = app.DataTime(t0_index);
                    app.T0TimeEditField.Value = app.T0_Time;

                    % Plot new image as time has changed
                    plotImage(app);

                    % For computational purposes, new t50 line is drawn after line has moved
                    % This can be done above as well, if needed, move the lines in the case
                    % to below 'app.T0_Time = src.Position(1);'
                case{'ROIMoved'}
                    % Update time above t0 as it is globab
                    app.TimeAbove0 = app.DataTime(app.DataTime >= app.T0_Time);
                    % Once T0 has moved, generate new fit functions
                    generateFitFCNS(app);
                    % Plot the new image using new fit functions
                    plotImage(app);
                    % Plot the t_50 line if we have active pixel
                    if ~isempty(app.CurrentKineticData) && ~(app.ActivePixel == -1)
                        % When CurrentKineticData is made, T50_Line will be
                        % made too

                        % Make T50 Line for data set

                        app.DataAbove0 = app.CurrentKineticData(app.DataTime >= app.T0_Time);


                        app.InfoLabel.Text = "Drawing...";
                        app.InfoLabel.Visible = "on";
                        % Get baseline coefficient value to find the t50% only from the decay part.
                        FitCoeffs = coeffvalues(app.FitFunctions{app.ActivePixel});
                        BaselineCoeff = FitCoeffs(4);
                        % Get T0 intensity from fit function
                        intensityatT0 = feval(app.FitFunctions{app.ActivePixel}, log10(app.T0_Time));
                        % Obtain t50% intensity of decay (i.e., consider the baseline offset)
                        t50_intensity = (intensityatT0+BaselineCoeff)/2;
                        % Define function for obtaining the time using the given intensity
                        t50_time_evaluator = @(x)t50_intensity - app.FitFunctions{app.ActivePixel}(x);
                        % Obtain the fitted time from fit function
                        logt50_time= fzero(t50_time_evaluator, log10(app.T0_Time));
                        % Convert time to base 10
                        t50_time = 10^logt50_time;

                        % Update plot
                        app.T50_Line.Value = t50_time;
                        app.InfoLabel.Visible = "off";
                    end
            end
        end

        function plotImage(app)
            pixelIntensitiesatT0 = arrayfun(@(x)feval(x{:}, log10(app.T0_Time)), app.FitFunctions);
            % all rows of pixel intensities, transposed to row as well
            logt50_times = zeros(length(pixelIntensitiesatT0),1);

            for index=1:length(pixelIntensitiesatT0)
                t50_intensity = pixelIntensitiesatT0(index)/2;
                % feval will generate intensity from time
                % but we want to pass an intensity and get a time
                t50_time_evaluator = @(x)t50_intensity - app.FitFunctions{index}(x);
                logt50_times(index) = fzero(t50_time_evaluator, log10(app.T0_Time));

                if isnan(logt50_times(index))
                    disp(['Fit at index ' num2str(index) ' returned a t50% time of NaN.'])
                end
            end

            if any(isnan(logt50_times))
                %throw(MException("TimeEvaluator:NaNError", "NaN value from fzero. Cannot complete image."));
                disp("Warning. One or more searches for t50% returned NaN. The NaN's are set to the lowest t50% time.")
                logt50_times(isnan(logt50_times)) = min(logt50_times);
            end

            t50_times = 10.^logt50_times;
            app.ExportT50Times = t50_times;
            histogram(app.HistPlot, log10(t50_times));
            app.HistPlot.XLim = log10([app.MinColorTime.Value app.MaxColorTime.Value]);
            t0_values = pixelIntensitiesatT0;
            app.ExportT0Int = t0_values;
            % Get current colorScheme
            colorScheme = app.ColorSchemeButtonGroup.SelectedObject.Text;

            % Use default turbo colormap
            if colorScheme =="Grayscale"
                colorMap = gray();
            else
                colorMap = turbo();
            end

            % Since data was sorted in same way as when pixel Array was
            % made, we can simple perform operations on the data table
            % we round the interpolation as table indices cannot be decimal
            % we interpolate the min and max t50 times to between 30 (a
            % value chosen by Robert
            %%%%% ASK WHY VALUE WAS CHOSEN, I'M NOT SURE
            % We then iterpolate the logarithms of the times
            %%% ASK ABOUT THIS ENTIRE PROCESS TBH, I DON'T KNOW
            %%% BUT HEY, IF IT AIN'T BROKEN DON'T FIX IT

            % Ensure maximum index covers highest t50 time
            if app.MaxColorIndex < max(t50_times)
                app.MaxColorIndex = max(t50_times);
                app.MaxColorTime.Value = app.MaxColorIndex;
            end

            % Ensure Mininum Index covers lowest t50 time
            if app.MinColorIndex > min(t50_times)
                app.MinColorIndex = min(t50_times);
                app.MinColorTime.Value = app.MinColorIndex;
            end

            colorIndices = round(interp1(log10([app.MinColorIndex, app.MaxColorIndex]), [30,length(colorMap)], log10(t50_times)));

            colors = colorMap(colorIndices, :);

            % If using intesity derived colorscheme
            if colorScheme == "Intensity Derived"
                % Convert colors to HSV
                colorsHSV = rgb2hsv(colors);
                % Obtain new brightness value
                new_V = interp1([0 max(abs(t0_values))], [0,1], abs(t0_values));
                % Set the new brightness values
                colorsHSV(:, 3) = new_V;
                % Rewrite colors
                colors = hsv2rgb(colorsHSV);
            elseif colorScheme == "Grayscale"

            end


            %             colorMap = colormap(app.UIAxes,colors);
            colormap(app.PixelPlot,colorMap(30:end,:));
            caxis(app.PixelPlot,log10([app.MinColorTime.Value app.MaxColorTime.Value]));

            % Generate m x m array same way as in making the image data
            % imageData = pagetranspose(reshape(colors, [sqrt(length(colors)), sqrt(length(colors)), 3]));
            col_len = sqrt(length(colors));
            imageData = reshape(colors, [col_len, col_len, 3]);
            if ~isempty(app.DrawnRectangles)
                delete(app.ImagePlot);
                % Plot image
                if colorScheme == "Grayscale"
                    n = sqrt(length(t50_times));
                    p =reshape(t0_values, [n, n, 1]);
                    app.ImagePlot =  imagesc(app.PixelPlot, p);
                    caxis(app.PixelPlot, [0.001 0.013])
                else
                    app.ImagePlot = image(app.PixelPlot,imageData);
                end
            else
                % Plot image
                if colorScheme == "Grayscale"
                    n = sqrt(length(t50_times));
                    p =reshape(t0_values, [n, n, 1]);
                    app.ImagePlot =  imagesc(app.PixelPlot, p);
                    caxis(app.PixelPlot, [0.001 0.013])
                else
                    app.ImagePlot = image(app.PixelPlot,imageData);
                end
            end

            colorbar(app.PixelPlot,'Visible','on');

            % Set tick size and then populate labels
            len = sqrt(length(app.ExportT50Times));

            % Turn coordinates into strings and format
            xtickLabels = num2str(app.finalX,'%.2f');
            ytickLabels = num2str(app.finalY,'%.2f');

            % Change amount of ticks if they start to overlap
            if len > 4
                app.PixelPlot.XTick = 2:2:len;
                app.PixelPlot.YTick = 2:2:len;

                xtickLabels = xtickLabels(2:2:len,:);
                ytickLabels = ytickLabels(2:2:len,:);
            else
                app.PixelPlot.XTick = 1:len;
                app.PixelPlot.YTick = 1:len;
            end

            % Assign coordinate labels to tick labels
            app.PixelPlot.XTickLabel = xtickLabels;
            app.PixelPlot.YTickLabel = ytickLabels;

            % Set Y axis to ascending
            app.PixelPlot.YDir = "normal";
            axis(app.PixelPlot,'image')

            % Image for saving
            app.ImageSaveData = imageData;

            % Show plot
            app.PixelPlot.Visible = "on";
            app.HistPlot.Visible = "on";

            % Add listener for click events
            app.ImagePlot.ButtonDownFcn = @(~, evt)imageClicked(app, evt);

            % After image is drawn, enable Select region button
            app.SelectRegionButton.Enable = "on";
        end
        % Function to handle when image plot is clicked
        function imageClicked(app, evt)

            % Get the clickedPixel
            pointClicked = round(evt.IntersectionPoint);
            pixelClicked = app.PixelCoordinates(pointClicked(1), pointClicked(2));

            sz = sqrt(length(app.ExportT50Times));
            fileInd = sub2ind([sz,sz],pointClicked(1),pointClicked(2));

            % Extract pixel data
            app.CurrentKineticData = app.DataTable.(pixelClicked);

            app.KinPlotTab.Title = "KinPlot - Channel: " + app.Channels(fileInd,1);
            if app.RedGreen(fileInd,1) == 1
                app.KinPlotTab.ForegroundColor = "g";
            else
                app.KinPlot.Title.Color = "r";
            end

            % SINCE THE DATATABLE IS MAINTAINED ALL THROUGH THE PROJECT WE
            % CAN EXTRACT THE INDEX OF SELECTED PIXEL
            app.ActivePixel = find(app.DataTable.Properties.VariableNames == pixelClicked);

            % Set the current Time and Data respectively
            app.TimeAbove0 = app.DataTime(app.DataTime >= app.T0_Time);
            app.DataAbove0 = app.CurrentKineticData(app.DataTime >= app.T0_Time);

            % Plot trace
            plotKineticTrace(app,pixelClicked);

        end
        % Function to plot kinetic trace. Will only plot app.CurrentKineticData
        function plotKineticTrace(app,pixelClicked)
            % Throw error if called before data is available
            assert(~isempty(app.CurrentKineticData), "CurrentKineticData must be set first");
            % delete old plots
            delete(app.CurrentPlot);
            delete(app.T50_Line);
            delete(app.FittedPlot);

            % Ensure there is a current active pixel
            if ~(app.ActivePixel == -1)
                % Get baseline coefficient value to find the t50% only from the decay part.
                FitCoeffs = coeffvalues(app.FitFunctions{app.ActivePixel});
                BaselineCoeff = FitCoeffs(4);
                % Get T0 intensity from fit function
                intensityatT0 = feval(app.FitFunctions{app.ActivePixel}, log10(app.T0_Time));
                % Obtain all intensities above t0
                intensities = feval(app.FitFunctions{app.ActivePixel}, app.FitX);
                % Obtain t50% intensity of decay (i.e., consider the baseline offset)
                t50_intensity = (intensityatT0+BaselineCoeff)/2;
                % Obtain the function
                t50_time_evaluator = @(x)t50_intensity - app.FitFunctions{app.ActivePixel}(x);
                % Evaluate function to get the time
                logt50_time = fzero(t50_time_evaluator, log10(app.T0_Time));
                % COnvert time to base 10
                t50_time = 10^logt50_time;
                % Plot line at y = 0
                yline(app.KinPlot, 0);

                % Hold the plot so we can plot two things
                % Plot the kinetic trace on plot
                app.CurrentPlot = line(app.KinPlot, app.DataTime, app.CurrentKineticData, "Color", [1 0 0], "DisplayName",pixelClicked);


                app.FittedPlot = line(app.KinPlot, app.TimeAbove0, intensities, "LineStyle","--", "Color", [0.1 0.1 0.1], "DisplayName","Fitted");
                % Create the t50 line for the plot
                app.T50_Line = xline(app.KinPlot,t50_time, "DisplayName","");

                % Add legend to plot
                legend(app.KinPlot,app.CurrentPlot);
            end


            if ~isempty(app.CurrentSelectedAreaData)
                delete(app.SelectedRegionPlot);
                app.SelectedRegionPlot = line(app.KinPlot, app.DataTime, app.CurrentSelectedAreaData, "Color",[0 0 1], "DisplayName","Rectangle Regions");
            end
        end

        % Function generates CurrentKineticData from drawn rectangles
        function getSelectedArea(app)
            % Create areas for all rectangles in DrawnRectangles
            drawnRects = app.DrawnRectangles;
            if ~isempty(drawnRects)
                % Extract positions
                positions = [drawnRects(:,:).Position];
                % Reshape into m x 4 array
                positions = reshape(positions, [4, (length(positions)/4)])';
                % Get bottom left co-ordinates
                bottoms = round([positions(:,1), positions(:,2)]);
                % Get top right
                tops = round([positions(:,1) + positions(:,3), positions(:,2)+positions(:,4)]);

                % Get the points covered by the rectangle
                %%%%% NOTE FOR FUTURE DEVELOPMENT
                %%%%% IF A METHOD CAN BE IMPLMEMENTED TO OBTAIN THE COVERED
                %%%%% POINTS MORE EFFICIENTLY PLEASE PLEASE PLEASE IMPLEMENT IT
                %%%%% THIS IS THE MOST INNEFIENT PART OF THE CODE!!!
                warning("off");
                pointsCovered = [];
                for point = 1:size(positions,1)
                    pointsCovered = [pointsCovered; reshape(app.PixelCoordinates(bottoms(point,1):tops(point,1), bottoms(point,2):tops(point,2)),[],1)];
                end
                warning("on");
                % Add covered pixels to selectedRegions
                % Extract the unique points to account for overlap, and to
                % prevent array from growing too large
                app.SelectedRegions = unique(pointsCovered);

                % Extract needed columns
                data = app.DataTable(:, ismember(app.DataTable.Properties.VariableNames, app.SelectedRegions));
                % assign data to the average of colums
                % CurrentSelectedAreaData = mean of all rows
                app.CurrentSelectedAreaData = mean(data{:,:}, 2);

                % ensure there is CurrentData
                if isempty(app.CurrentKineticData)
                    app.CurrentKineticData = app.CurrentSelectedAreaData;
                end

                % Once we have clean CurrentKineticData, we plot
                plotKineticTrace(app);

                % If we have rectArea, enable delete and add buttons
                if ~isempty(app.DrawnRectangles)
                    app.RemoveButton.Enable = "on";
                    app.AddRegionButton.Enable = "on";
                end
            else
                app.RemoveButton.Enable = "off";
                app.AddRegionButton.Enable = "off";
                app.SelectRegionButton.Enable = "on";
            end
        end

        function deleteRectangle(app, rectangle)
            % Extract rectangle id
            rectID = rectangle.UserData;
            % Filter out rectangle from list of drawn rectangles
            filterIndex = [app.DrawnRectangles.UserData];
            app.DrawnRectangles = app.DrawnRectangles(filterIndex ~= rectID);

            % Reduce the index
            app.rectangleID = app.rectangleID - 1;
            % Delete the rectangle
            delete(rectangle);
            % Redraw selected areas
            getSelectedArea(app);
        end

        % Function to generate fit for all data
        function generateFitFCNS(app)
            app.InfoLabel.Text = "Please wait...";
            app.InfoLabel.Visible = "on";
            % start time (not needed)
            % StartFitTime = log10(app.T0_Time); %Time above t0
            % Defining the fit functionfor eval
            linfittype = fittype('a/(1+b*10^x)^c + d');
            % Define the fit options for the line
            %             fopt = fitoptions('Method','NonLinearLeastSquares');

            % These are the values correspondiong to a,b,c and d
            %             fopt.Lower = [-inf -inf 0 -inf];
            %             fopt.Upper = [inf inf 1 inf];

            % Addition fitting options to more consistently get appropriate
            % fits.
            %             fopt.TolFun = 1E-10;
            %             fopt.Robust="Bisquare";

            % Obtain the logarithm of all time above 0
            LogLogTime = log10(app.TimeAbove0);
            % Filter data to data above 0
            data = app.DataTable{app.DataTime >= app.T0_Time,:};
            % Prepare data of same size (same columns)
            [rowsize,colsize]=size(data);
            % It's less memory exhaustive to use loop
            % We know data size to expect
            app.FitX = zeros(rowsize,1);
            % Keeps track of all fittedY
            app.FitY = zeros(rowsize, colsize);
            app.FitFunctions = cell(colsize,1);
            tic


            %Updated initial parameter estimates
            Initial_amplitude = mean(data(1:50,:))';              % Average first 50 points to get initial amplitude guess
            Initial_corner = 1/(app.DataTime(round(length(app.DataTime)/2))); % Initial guess for b parameter at half the time window
            Initial_alpha = 0.5;                            % Use 0.5 as initial alpha guess
            Initial_baseline = mean(data(end-9:end,:))';    % Average last 10 points to get initial baseline guess


            %Old method for initial parameter estimates
            %for i = 1:colsize
            %points(i,:) = [data(1,i) CornerEstimate 0.5 data(end,i)];
            % set infinite baseline to the last index of the fitY data
            % set starting intensity to that at time0 to be able to handle both positive and negative initial amplitudes
            %end


            for column = 1:colsize  % Not parallel as it seems it can mess up the X data
                % prepare fit data for all pixels in image
                [app.FitX, app.FitY(:,column)] = prepareCurveData(LogLogTime, data(:,column));

            end

            X = app.FitX;
            Y = app.FitY;


            % Find the delta from the largest amplitude (positive or negative) to use for baseline fit bounds.
            CondMaxValues = max(data,[],'ComparisonMethod','abs')';      % Maximum positive or negative values to take to find the delta signal.
            deltaSignal = abs(CondMaxValues - Initial_baseline);

            % PARALLELIZE HERE
            parfor column = 1:colsize

                % Define range of data which to exclude (now redundant - data only has values for time > t0)
                % Extremeties don't work with this line enabled
                %fopt.Exclude = app.FitX < StartFitTime;
                Fits{column} = fit(X, Y(:,column), linfittype,'Lower',[-inf 0 0 Initial_baseline(column)-deltaSignal(column)/5],...
                    'Upper',[inf inf 1 Initial_baseline(column)+deltaSignal(column)/5],'TolFun',1E-10,'Robust','Bisquare',...
                    'StartPoint',[Initial_amplitude(column),Initial_corner,Initial_alpha,Initial_baseline(column)]);
            end
            % for column=1:1:colsize
            %     % prepare fit data for all pixels in image
            %     [app.FitX, app.FitY(:,column)] = prepareCurveData(LogLogTime, data(:,column));
            %     % set starting intensity to 0 to be able to handle both positive and negative initial amplitudes
            %     % set infinite baseline to the last index of the fitY data
            %     fopt.StartPoint = [0 100 0.5 app.FitY(end, column)];
            %     % Define range of data which to exclude (now redundant - data only has values for time > t0)
            %     % Extremeties don't work with this line enabled
            %     %fopt.Exclude = app.FitX < StartFitTime;
            %     app.FitFunctions{column} = fit(app.FitX, app.FitY(:,column), linfittype,fopt);
            % end
            app.FitFunctions = Fits;
            toc
            app.InfoLabel.Visible = "off";
        end

    end
    % Accesible by dialog
    methods (Access = public)
        function passDataToMain(app, preDataTable, preDataTime, preDataDC, pixelCoordinates, fullName)
            % Perfom filtering of data
            positive_time = preDataTime(preDataTime > 0);
            positive_time_data = preDataTable(preDataTime > 0, :);

            % Store the loaded global variables
            app.DataTable = positive_time_data;
            app.DataTime = positive_time;
            app.DataDC = preDataDC;

            app.PixelCoordinates = pixelCoordinates;
            app.FullName = fullName;

            app.MaxDataTime = max(positive_time);

            % Once data is loaded, time to start the functions
            % Plot the average data, and set the t0 time
            try
                plotAverage(app);
            catch E
                disp("Problem plotting average data. Refer below for details")
                disp(E);
            end

            % Load the time
            app.TimeAbove0 = app.DataTime(app.DataTime >= app.T0_Time);

            % Generate all the fit functions
            try
                generateFitFCNS(app);
            catch E
                disp("Problem generating Fit Functions. Refer below for details")
                disp(E);
            end

            % Generate background level heatmap
            col_len = sqrt(size(app.DataDC,2));
            DCmat = table2array(app.DataDC);
            DCmat = reshape(DCmat, [col_len, col_len]);

            % Plot the image
            try
                plotImage(app);
            catch E
                disp("Problem plotting pixel image. Refer below for details")
                disp(E);
            end

            % Once image is made enable SchemeSwitch
            app.ColorSchemeButtonGroup.Enable = "on"; %%%%%--------------Colorscheme

            % Enable functionality
            app.LoadDataButton.Enable = "on";
            app.T0TimeEditField.Enable = "on";
            app.ExportDataButton.Enable = "on";
            app.MaxColorTime.Enable = "on";
            app.MinColorTime.Enable = "on";

            % Create heatmap of Bkgrd DC levels and flip to keep consistent with image plot
            HM = heatmap(app.Panel,DCmat);
            HM.YDisplayData = flip(HM.YDisplayData);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartFcn(app)
            % Check if MATLAB addons are installed
            try
                matlab.addons.isAddonEnabled('Image Processing Toolbox');
                matlab.addons.isAddonEnabled('Curve Fitting Toolbox');
            catch error
                disp(error);
                uialert(app.UIFigure,"Please ensure the 'Image Processing Toolbox' and 'Curve Fitting Toolbox' are installed to continue","Addon Required","Icon","error");
                return;
            end

            % Set AvgPlot and KinPlot to Log
            app.AvgPlot.XScale = "log";
            app.KinPlot.XScale = "log";

            % Disable graphs from being interactive i.e moving around
            disableDefaultInteractivity(app.AvgPlot);
            disableDefaultInteractivity(app.KinPlot);
            disableDefaultInteractivity(app.PixelPlot);

            % Determine where your m-file's folder is.
            folder = fileparts(which(mfilename));

            % Add that folder plus all subfolders to the path.
            addpath(genpath(folder));

            % Add color par to pixel plot and make it invisible for efficiency
            OGPos = app.PixelPlot.Position;
            colorbar(app.PixelPlot,'Visible','off');
            app.PixelPlot.Position = OGPos;
        end

        % Button pushed function: LoadDataButton
        function loadData(app, event)
            % Disable button, until step size provided
            app.LoadDataButton.Enable = "off";

            % Read the data from folder
            readData(app);

            try
                % Create image data
                makeImageData(app);
                % Pass data back to main App
                passDataToMain(app,app.preDataTable, app.preDataTime, app.DClevelTable, app.pixelCoordinates, app.fullName);

                app.LoadDataButton.Text = "Load New Data";

                % Error handlers
            catch ME
                switch ME.identifier
                    case "ImageData:InvalidStepSize"
                        disp("Invalid Step Size");
                        app.InfoLabel.Text = "Invalid Step Size";
                    case "ImageData:SizeError"
                        disp("Dataset is not square")
                        app.InfoLabel.Text = "Dataset is not square";
                    case "ImageData:StepSizeError"
                        disp("Stepsize could not create square image")
                        app.InfoLabel.Text = "Stepsize could not create square image";
                    otherwise
                        disp(ME.message)
                end
                app.InfoLabel.Visible = "on";
                app.LoadDataButton.Enable = "on";
                return
            end
        end

        % Selection changed function: ColorSchemeButtonGroup
        function colorSchemeChange(app, event)
            % replot image if scheme changes
            plotImage(app);

        end

        % Button pushed function: SelectRegionButton
        function selectRegionBtnPressed(app, event)
            % Delete previous selected regions
            if ~isempty(app.SelectedRegions)
                app.SelectedRegions = [];
            end
            if ~isempty(app.DrawnRectangles)
                delete(app.DrawnRectangles);
                app.DrawnRectangles = [];
            end


            % Disable button to prevent errors
            app.SelectRegionButton.Enable = "off";
            app.RectDrawInfo.Visible = "on";

            % Start new drawing area
            drawnRectangle = drawrectangle(app.PixelPlot);
            % Ensure rectangle gets drawn
            if ~isempty(drawnRectangle.Position)
                % Addfunctionality to the rectangles
                % Add rectangle ID that corresponds to location on array
                drawnRectangle.UserData = app.rectangleID;
                % Increment rectangle ID
                app.rectangleID = app.rectangleID + 1;

                % Use the default menu, instead of creacting new menu
                % Delete handler
                drawnRectangle.ContextMenu.Children(1).MenuSelectedFcn = ...
                    @(~,~) deleteRectangle(app, drawnRectangle);

                % Add listener for when roi changes shape/moves
                % callBack will call plotKineticTrace with appropriate dataset
                addlistener(drawnRectangle, "ROIMoved", @(~, ~)getSelectedArea(app));
                % add to drawnRectangles
                app.DrawnRectangles = (drawnRectangle);

                % Get area
                getSelectedArea(app);
                % Enable the two buttons
                app.AddRegionButton.Visible = "on";
                app.RemoveButton.Visible = "on";
            end
            app.RectDrawInfo.Visible = "off";

        end

        % Button pushed function: AddRegionButton
        function addRegionBtnPressed(app, event)
            % Adds new rectangle without deleting old ones

            % Let user know they can cancel current drawing
            app.RectDrawInfo.Visible = "on";

            % Start new drawing area
            drawnRectangle = drawrectangle(app.PixelPlot);

            % Ensure the rectangle isn't empty
            if ~isempty(drawnRectangle.Position)
                % Add functionality to the rectangles
                % Add rectangle ID that corresponds to location on array
                drawnRectangle.UserData = app.rectangleID;
                % Increment rectangle ID
                app.rectangleID = app.rectangleID + 1;

                % Use the default menu, instead of creacting new menu
                % Delete handler
                drawnRectangle.ContextMenu.Children(1).MenuSelectedFcn = ...
                    @(~,~) deleteRectangle(app, drawnRectangle);

                % Add listener for when roi changes shape/moves
                % callBack will call plotKineticTrace with appropriate dataset
                addlistener(drawnRectangle, "ROIMoved", @(src, ~)getSelectedArea(app));

                % add to drawnRectangles
                app.DrawnRectangles = [app.DrawnRectangles; drawnRectangle];

                % Get area
                getSelectedArea(app);
            end
            app.RectDrawInfo.Visible = "off";
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch(key)
                case "escape"
                    % If first drawing cancelled
                    if isempty(app.DrawnRectangles)
                        app.SelectRegionButton.Enable = "on";
                        % Disable the two buttons
                        app.AddRegionButton.Visible = "off";
                        app.RemoveButton.Visible = "off";
                    end

            end
        end

        % Button pushed function: RemoveButton
        function deleteRectangles(app, event)
            % Delete all rectangles
            delete(app.DrawnRectangles);
            delete(app.SelectedRegionPlot);
            app.CurrentSelectedAreaData = [];
            app.DrawnRectangles = [];
            % Reset count
            app.rectangleID = 1;
            % Enable new region
            app.AddRegionButton.Visible = "off";
            app.RemoveButton.Visible = "off";
            app.SelectRegionButton.Enable = "on";
        end

        % Value changed function: T0TimeEditField
        function T0Changed(app, event)
            value = app.T0TimeEditField.Value;
            % Ensure input value is within range
            if value > app.MaxDataTime
                app.T0_Time = app.MaxDataTime;
            elseif value < min(app.DataTime)
                app.T0_Time = min(app.DataTime);
            else
                % Ensure value input is linked to a value in table
                [~, t0_index] = min(abs(app.DataTime - value));
                app.T0_Time = app.DataTime(t0_index);
                % Set
                app.T0_line.Position = [app.T0_Time -1; app.T0_Time 1];
                app.T0_line_kinPlot.Value = app.T0_Time;
            end
            app.TimeAbove0 = app.DataTime(app.DataTime >= app.T0_Time);
            % When the T0 time is changed by input, generate new fit fcns
            generateFitFCNS(app);
            % Plot new pixel plot
            plotImage(app);
            % Update the t50 line
            if ~isempty(app.CurrentKineticData)
                % When CurrentKineticData is made, T50_Line will be
                % made too

                % Make T50 Line for data set

                app.DataAbove0 = app.CurrentKineticData(app.DataTime >= app.T0_Time);


                app.InfoLabel.Text = "Drawing...";
                app.InfoLabel.Visible = "on";

                % Get baseline coefficient value to find the t50% only from the decay part.
                FitCoeffs = coeffvalues(app.FitFunctions{app.ActivePixel});
                BaselineCoeff = FitCoeffs(4);
                % Get T0 intensity from fit function
                intensityatT0 = feval(app.FitFunctions{app.ActivePixel}, log10(app.T0_Time));
                % Obtain t50% intensity of decay (i.e., consider the baseline offset)
                t50_intensity = (intensityatT0+BaselineCoeff)/2;
                % Define function for obtaining the time using the given intensity
                t50_time_evaluator = @(x)t50_intensity - app.FitFunctions{app.ActivePixel}(x);
                % Obtain the fitted time from fit function
                logt50_time= fzero(t50_time_evaluator, log10(app.T0_Time));
                % Convert time to base 10
                t50_time = 10^logt50_time;

                % Update plot
                app.T50_Line.Value = t50_time;
                app.InfoLabel.Visible = "off";
            end

        end

        % Button pushed function: ExportDataButton
        function ExportDataPushed(app, event)
            % Write the image and make dir
            selpath = uigetdir(app.FullName);
            mkdir(selpath+"\ExportedData");

            % Old image extraction
            % s = getframe(app.PixelPlot);
            % imwrite(s.cdata, selpath+"\ExportedData\ImagePlot.jpg");

            % Need to add empty space for proper size ratio of export
            temp = app.PixelPlot.Title.String;
            app.PixelPlot.Title.String = " ";
            exportgraphics(app.PixelPlot,selpath+"\ExportedData\ImagePlot.emf", "Resolution",600);
            app.PixelPlot.Title.String = temp;

            % Save various data types
            imageData = app.ImageSaveData;
            save(selpath+"\ExportedData\ImageData.mat", "imageData");

            selectedRegions = app.SelectedRegions;
            save(selpath+"\ExportedData\ROIPoints.mat", "selectedRegions");

            time = app.DataTime;
            pixelData = app.CurrentKineticData;
            ROIData = app.CurrentSelectedAreaData;
            save(selpath+"\ExportedData\KinPlotData.mat", "time", "pixelData", "ROIData");

            Log10HistData = log10(app.ExportT50Times);
            save(selpath+"\ExportedData\Log10HistData.mat", "Log10HistData");

            % Plot t50 data in seperate window
            % Get current colorScheme
            colorScheme = app.ColorSchemeButtonGroup.SelectedObject.Text;
            if colorScheme =="Greyscale"
                colorMap = gray();
            else
                colorMap = turbo();
            end

            fig = figure('Colormap', colorMap(30:end,:));
            ax = axes(fig);
            %             set(fig, 'currentaxes', ax)

            colormap(ax,colorMap(30:end,:));

            if colorScheme == "Grayscale"
                n = sqrt(length(app.ExportT50Times));
                colormap(gray());
                p =reshape(app.ExportT0Int, [n, n, 1]);
                imagesc(ax, p);
                caxis(ax, [0.001 0.013])
            else
                image(ax,app.ImageSaveData);
                caxis(ax,log10([app.MinColorTime.Value app.MaxColorTime.Value]));
            end

            colorbar(ax,'Visible','on');


            % Set tick size and then populate labels
            len = sqrt(length(app.ExportT50Times));

            % Turn coordinates into strings and format
            xtickLabels = num2str(app.finalX,'%.2f');
            ytickLabels = num2str(app.finalY,'%.2f');

            % Change amount of ticks if they start to overlap
            if len > 4
                ax.XTick = 2:2:len;
                ax.YTick = 2:2:len;

                xtickLabels = xtickLabels(2:2:len,:);
                ytickLabels = ytickLabels(2:2:len,:);
            else
                ax.XTick = 1:len;
                ax.YTick = 1:len;
            end

            % Assign coordinate labels to tick labels
            ax.XTickLabel = xtickLabels;
            ax.YTickLabel = ytickLabels;

            % Set Y axis to ascending
            ax.YDir = "normal";

            ax.XLabel.String = app.PixelPlot.XLabel.String;
            ax.YLabel.String = app.PixelPlot.YLabel.String;

            % Plot T0 intensities in seperate window
            %             n = sqrt(length(app.ExportT50Times));
            %             exportT0_intensities = reshape(app.ExportT0Int, [n, n, 1]);
            %             fig2 = figure;
            %             ax2 = axes(fig2);
            %             image(ax2, exportT0_intensities);
        end

        % Value changed function: MaxColorTime, MinColorTime
        function TimeColorScaleChanged(app, event)
            minInd = app.MinColorTime.Value;
            maxInd = app.MaxColorTime.Value;

            if minInd > maxInd
                app.InfoLabel.Text = "Min must be less than max";
                app.InfoLabel.Visible = "on";
            else
                app.InfoLabel.Visible = "off";
                app.MinColorIndex = minInd;
                app.MaxColorIndex = maxInd;

                % Removed to be able to chose color scale limits that are
                % past the min and max.
                %                 if minInd < min(app.DataTime)
                %                     app.MinColorIndex = min(app.DataTime);
                %                     app.MinColorTime.Value = app.MinColorIndex;
                %                 end
                %                 if maxInd > app.MaxDataTime
                %                     app.MaxColorIndex = app.MaxDataTime;
                %                     app.MaxColorTime.Value = app.MaxColorIndex;
                %                 end

            end
            plotImage(app);
        end

        % Button pushed function: ExportVariablesButton
        function exportVars(app, event)
            n = sqrt(length(app.ExportT50Times));

            assignin("base","DataTime", app.DataTime);
            assignin("base","DataTable", app.DataTable);

            DCmat = table2array(app.DataDC);
            DCmat = reshape(DCmat, [n, n])';


            assignin("base","DataDC", DCmat);
            assignin("base","PixelCoordinated", app.PixelCoordinates');
            exportT50_times = fliplr(reshape(app.ExportT50Times, [n, n, 1])); % Flip horizontally
            assignin("base","currentT50Times", exportT50_times);
            exportT0_intensities = fliplr(reshape(app.ExportT0Int, [n, n, 1])); % Flip horizontally
            assignin("base","exportT0_intensities", exportT0_intensities);
            assignin("base","FitCoefficients",app.FitFunctions);


            % Put everything in a structure to keep organized
            StructData = struct;
            StructData.PixelCoordinates = app.PixelCoordinates;
            StructData.DClevel = DCmat;
            StructData.T0Int = exportT0_intensities;
            StructData.T50times = exportT50_times;
            StructData.TimeAboveT0= app.TimeAbove0;

            FitObjArray = reshape(app.FitFunctions, [n, n])';
            StructData.fits = FitObjArray;

            TempArray = table2array(app.DataTable);
            OGDataLength = size(TempArray,1);
            Above0DataLength = length(app.TimeAbove0);
            TempArray = TempArray(OGDataLength-Above0DataLength+1:end,:);
            CellArray = num2cell(TempArray,1);
            StructData.ExperimentalDecays = reshape(CellArray, [n, n])';

            assignin("base","StructData",StructData);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {629, 629};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function onCloseFcn(app, event)
            % Delete DialogLoader and its variables
            delete(app.DataLoaderDialogApp);
            % Delete current app, and all variables
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TAM_Analyzer';
            app.UIFigure.Icon = 'Logo.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @onCloseFcn, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.LoadDataButton = uibutton(app.GridLayout4, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadData, true);
            app.LoadDataButton.Text = 'Load Data';
            app.InfoLabel = uilabel(app.GridLayout4);
            app.InfoLabel.Visible = 'off';
            app.InfoLabel.Text = 'Loading...';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ColorSchemeButtonGroup = uibuttongroup(app.GridLayout5);
            app.ColorSchemeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @colorSchemeChange, true);
            app.ColorSchemeButtonGroup.Tooltip = {'Change between color schemes'};
            app.ColorSchemeButtonGroup.Enable = 'off';
            app.IntensityDerivedButton = uiradiobutton(app.ColorSchemeButtonGroup);
            app.IntensityDerivedButton.Text = 'Intensity Derived';
            app.IntensityDerivedButton.Value = true;
            app.RGBButton = uiradiobutton(app.ColorSchemeButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.GrayscaleButton = uiradiobutton(app.ColorSchemeButtonGroup);
            app.GrayscaleButton.Text = 'Grayscale';
            app.ColorSchemeLabel = uilabel(app.GridLayout5);
            app.ColorSchemeLabel.Text = 'Color Scheme';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.AddRegionButton = uibutton(app.GridLayout6, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @addRegionBtnPressed, true);
            app.AddRegionButton.Enable = 'off';
            app.AddRegionButton.Visible = 'off';
            app.AddRegionButton.Text = 'Add Region';
            app.RemoveButton = uibutton(app.GridLayout6, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @deleteRectangles, true);
            app.RemoveButton.Icon = 'Delete.png';
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Visible = 'off';
            app.RemoveButton.Text = 'Remove';
            app.SelectRegionButton = uibutton(app.GridLayout4, 'push');
            app.SelectRegionButton.ButtonPushedFcn = createCallbackFcn(app, @selectRegionBtnPressed, true);
            app.SelectRegionButton.Enable = 'off';
            app.SelectRegionButton.Text = 'Select Region';
            app.RectDrawInfo = uilabel(app.GridLayout4);
            app.RectDrawInfo.Visible = 'off';
            app.RectDrawInfo.Text = 'Press ''esc'' to cancel draw';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.T0TimeEditFieldLabel = uilabel(app.GridLayout7);
            app.T0TimeEditFieldLabel.Text = 'T0 Time';
            app.T0TimeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.T0TimeEditField.ValueChangedFcn = createCallbackFcn(app, @T0Changed, true);
            app.T0TimeEditField.Enable = 'off';
            app.ExportDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.MinLabel = uilabel(app.GridLayout9);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GridLayout9);
            app.MaxLabel.Text = 'Max';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.MinColorTime = uieditfield(app.GridLayout10, 'numeric');
            app.MinColorTime.ValueChangedFcn = createCallbackFcn(app, @TimeColorScaleChanged, true);
            app.MinColorTime.Enable = 'off';
            app.MaxColorTime = uieditfield(app.GridLayout10, 'numeric');
            app.MaxColorTime.ValueChangedFcn = createCallbackFcn(app, @TimeColorScaleChanged, true);
            app.MaxColorTime.Enable = 'off';
            app.ColorScaleTimesLabel = uilabel(app.GridLayout8);
            app.ColorScaleTimesLabel.Text = 'Color Scale Times';
            app.ExportVariablesButton = uibutton(app.GridLayout4, 'push');
            app.ExportVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @exportVars, true);
            app.ExportVariablesButton.Text = 'Export Variables';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightMainGrid = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.RightMainGrid);
            app.KinPlotTab = uitab(app.TabGroup);
            app.KinPlotTab.Title = 'KinPlot';
            app.GridLayout12 = uigridlayout(app.KinPlotTab);
            app.KinPlot = uiaxes(app.GridLayout12);
            title(app.KinPlot, 'Kinetic Plot')
            xlabel(app.KinPlot, 'Time')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            zlabel(app.KinPlot, 'Z')
            app.KinPlot.Visible = 'off';
            app.HistPlotTab = uitab(app.TabGroup);
            app.HistPlotTab.Title = 'HistPlot';
            app.GridLayout13 = uigridlayout(app.HistPlotTab);
            app.HistPlot = uiaxes(app.GridLayout13);
            title(app.HistPlot, 'T50 Time Histogram')
            xlabel(app.HistPlot, 'Log10 (time)')
            ylabel(app.HistPlot, 'Counts')
            app.HistPlot.Visible = 'off';
            app.BkgrdPlotTab = uitab(app.TabGroup);
            app.BkgrdPlotTab.Title = 'BkgrdPlot';
            app.GridLayout15 = uigridlayout(app.BkgrdPlotTab);
            app.Panel = uipanel(app.GridLayout15);
            app.GridLayout14 = uigridlayout(app.RightMainGrid);
            app.PixelPlot = uiaxes(app.GridLayout14);
            title(app.PixelPlot, 'Image')
            xlabel(app.PixelPlot, 'X (µm)')
            ylabel(app.PixelPlot, 'Y (µm)')
            app.PixelPlot.XTick = [];
            app.PixelPlot.YTick = [];
            app.PixelPlot.ZTick = [];
            app.PixelPlot.Visible = 'off';
            app.AvgPlot = uiaxes(app.GridLayout14);
            title(app.AvgPlot, 'Average Kinetic Trace')
            xlabel(app.AvgPlot, 'Time')
            ylabel(app.AvgPlot, 'ΔOD or %Abs')
            zlabel(app.AvgPlot, 'Z')
            app.AvgPlot.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAM_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end